classdef WaveTankAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Settings                      matlab.ui.container.Tab
        Theheightofthetankis47centimetersLabel  matlab.ui.control.Label
        CMPerPixelValue               matlab.ui.control.Label
        CMPerPixelLabel               matlab.ui.control.Label
        pixelsPerCMValue              matlab.ui.control.Label
        pixelsPerCMLabel              matlab.ui.control.Label
        VideoSourceLabel              matlab.ui.control.Label
        inputOption                   matlab.ui.control.DropDown
        centimetersSpinner            matlab.ui.control.Spinner
        centimetersSpinnerLabel       matlab.ui.control.Label
        AfterLabel_2                  matlab.ui.control.Label
        Line1Slider                   matlab.ui.control.Slider
        BeforeLabel_2                 matlab.ui.control.Label
        Line2Slider                   matlab.ui.control.Slider
        CalibrationLabel              matlab.ui.control.Label
        WholeVideoSizeLabel           matlab.ui.control.Label
        ViewButton                    matlab.ui.control.Button
        Crop4Slider                   matlab.ui.control.Slider
        YPositionLabel                matlab.ui.control.Label
        Crop3Slider                   matlab.ui.control.Slider
        XpositionLabel                matlab.ui.control.Label
        Crop2Slider                   matlab.ui.control.Slider
        HeightLabel_2                 matlab.ui.control.Label
        Crop1Slider                   matlab.ui.control.Slider
        WidthLabel_2                  matlab.ui.control.Label
        AIVerticalPosition            matlab.ui.control.Slider
        VerticalSlider_2Label         matlab.ui.control.Label
        AIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSlider_2Label       matlab.ui.control.Label
        BIVerticalPosition            matlab.ui.control.Slider
        HorizontalLabel               matlab.ui.control.Label
        BIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSliderLabel         matlab.ui.control.Label
        AfterImpactBoxPositionLabel   matlab.ui.control.Label
        BeforeImpactBoxPositionLabel  matlab.ui.control.Label
        AIsinglePoint                 matlab.ui.control.Slider
        AfterLabel                    matlab.ui.control.Label
        BIsinglePoint                 matlab.ui.control.Slider
        BeforeLabel                   matlab.ui.control.Label
        ViewBoxWidth                  matlab.ui.control.Slider
        WidthLabel                    matlab.ui.control.Label
        ViewBoxHeight                 matlab.ui.control.Slider
        HeightLabel                   matlab.ui.control.Label
        SinglePointLabel              matlab.ui.control.Label
        ViewBoxSizeLabel              matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        SettingsVideo                 matlab.ui.control.UIAxes
        Viewer                        matlab.ui.container.Tab
        TimeElapsedUnitSwitchLabel    matlab.ui.control.Label
        TimeElapsedUnitSwitch         matlab.ui.control.Switch
        ExportSettingsPanel           matlab.ui.container.Panel
        CloseButton                   matlab.ui.control.Button
        ExportGraphsButton2           matlab.ui.control.Button
        FolderNameEditField           matlab.ui.control.EditField
        FolderNameEditFieldLabel      matlab.ui.control.Label
        ExportGraphsButton            matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ResetDataButton               matlab.ui.control.Button
        graphViewTab2                 matlab.ui.container.TabGroup
        amplitudeGraphTab2            matlab.ui.container.Tab
        amplitudeGraph2               matlab.ui.control.UIAxes
        singlePointViewTab2           matlab.ui.container.Tab
        singlePointGraph2             matlab.ui.control.UIAxes
        PeriodTab2                    matlab.ui.container.Tab
        periodGraph2                  matlab.ui.control.UIAxes
        graphViewTab1                 matlab.ui.container.TabGroup
        amplitudeGraphTab1            matlab.ui.container.Tab
        amplitudeGraph1               matlab.ui.control.UIAxes
        singlePointViewTab            matlab.ui.container.Tab
        singlePointGraph1             matlab.ui.control.UIAxes
        PeriodTab1                    matlab.ui.container.Tab
        periodGraph1                  matlab.ui.control.UIAxes
        wavelengthTab1                matlab.ui.container.Tab
        wavelengthGraph1              matlab.ui.control.UIAxes
        VelocityTab                   matlab.ui.container.Tab
        velocityGraph1                matlab.ui.control.UIAxes
        AIvideo                       matlab.ui.control.UIAxes
        BIvideo                       matlab.ui.control.UIAxes
        video                         matlab.ui.control.UIAxes
        InstructionManualTab          matlab.ui.container.Tab
        ViewBoxSizeLabel_2            matlab.ui.control.Label
        InstructionmanualLabel        matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function main(app)
            % Initializes the video input the user chooses
            if strcmp(app.inputOption.Value,'Video') == 1
                disp("The input is 'Video'")
                originalVideo = vision.VideoFileReader("OurWave2.mp4");
                videoPlayer = vision.VideoPlayer;
            else
                disp("The input is '" + app.inputOption.Value + "'")
                cam = webcam(app.inputOption.Value);
            end

            global crop
            global limit
            global increment
            limit = 1000;
            increment = 1000;
            
            % Initializing matricies and the time value for the graph
            global frameNum
            global BIAmplitudeMatrix
            global BISinglePointMatrix
            global AIAmplitudeMatrix
            global AISinglePointMatrix
            global BIWavelengthMatrix
            global AIWavelengthMatrix
            global BIPeriodMatrixFrames
            global AIPeriodMatrixFrames
            global BIVelocityMatrix
            global timeMatrix
            frameNum = 1;
            BIAmplitudeMatrix = NaN(limit,1);
            BISinglePointMatrix = NaN(limit,1);
            AIAmplitudeMatrix = NaN(limit,1);
            AISinglePointMatrix = NaN(limit,1);
            BIWavelengthMatrix = NaN(limit,1);
            AIWavelengthMatrix = NaN(limit,1);
            BIPeriodMatrixFrames = NaN(limit,1);
            AIPeriodMatrixFrames = NaN(limit,1);
            BIVelocityMatrix = NaN(limit,1);
            timeMatrix = NaN(limit, 1);

            % Prevents the user from modifying the settings mid-wave
            ViewBoxHeightValue = app.ViewBoxHeight.Value;
            ViewBoxWidthValue = app.ViewBoxWidth.Value;
            BIVerticalPositionValue = app.BIVerticalPosition.Value;
            BIHorizontalPositionValue = app.BIHorizontalPosition.Value;
            AIVerticalPositionValue = app.AIVerticalPosition.Value;
            AIHorizontalPositionValue = app.AIHorizontalPosition.Value;
            BISinglePointValue = app.BIsinglePoint.Value;
            AISinglePointValue = app.AIsinglePoint.Value;
            crop(1) = app.Crop1Slider.Value;
            crop(2) = app.Crop2Slider.Value;
            crop(3) = app.Crop3Slider.Value;
            crop(4) = app.Crop4Slider.Value;
            topLineSlider = app.Line1Slider.Value;
            bottomLineSlider = app.Line2Slider.Value;
            BIRowMatrix = NaN(0, round(ViewBoxWidthValue)+1)

            % Calculate how many pixels are in a centimeter
            % Just recalculated it instead of using global variable
            pixelsPerCM = abs(topLineSlider - bottomLineSlider) / app.centimetersSpinner.Value;
            CMPerPixel = app.centimetersSpinner.Value / abs(topLineSlider - bottomLineSlider);
            global on
            on = 1;
            GraphTic = tic;

            while on == 1
                % Extends the preallocation size of the matrix if it passes the
                % limit. I'm not really sure if this does anything, or
                % anything positive to be specific.
                if mod(frameNum, limit) == 0
                    limit = limit + increment
                    BIAmplitudeMatrix = vertcat(BIAmplitudeMatrix, NaN(increment, 1));
                    BISinglePointMatrix = vertcat(BISinglePointMatrix, NaN(increment, 1));
                    AIAmplitudeMatrix = vertcat(AIAmplitudeMatrix, NaN(increment, 1));
                    AISinglePointMatrix = vertcat(AISinglePointMatrix, NaN(increment, 1));
                    BIWavelengthMatrix = vertcat(AISinglePointMatrix, NaN(increment, 1));
                    AIWavelengthMatrix = vertcat(AISinglePointMatrix, NaN(increment, 1));
                    BIPeriodMatrixFrames = vertcat(AISinglePointMatrix, NaN(increment, 1));
                    AIPeriodMatrixFrames = vertcat(AISinglePointMatrix, NaN(increment, 1));
                    BIVelocityMatrix = vertcat(BIVelocityMatrix, NaN(increment, 1));

                    timeMatrix = vertcat(timeMatrix, Nan());
                end

                if strcmp(app.inputOption.Value,'Video') == 1
                    videoPlayer(originalVideo) %moves it to the next frame I think
                    croppedVid = imcrop(originalVideo(), [crop(1),crop(2),crop(3),crop(4)]);
                else
                    croppedVid = imcrop(snapshot(cam), [crop(1),crop(2),crop(3),crop(4)]);
                end

                % Video with boxes
                singlePointVid = insertShape(insertShape(croppedVid,'line', [AISinglePointValue 0 AISinglePointValue crop(4)], 'LineWidth', 2, 'Color', 'blue'),'line', [BISinglePointValue 0 BISinglePointValue crop(4)], 'LineWidth', 2, 'Color', 'blue');
                vidWithRulerLines = insertShape(insertShape(singlePointVid,'line', [0 bottomLineSlider crop(3) bottomLineSlider], 'LineWidth', 2, 'Color', 'blue'),'line', [0 topLineSlider crop(3) topLineSlider], 'LineWidth', 2, 'Color', 'blue');

                imshow(insertShape(insertShape(vidWithRulerLines, 'filled-rectangle', [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue], 'LineWidth', 2, 'Color', 'Blue'), 'filled-rectangle', [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue], 'LineWidth', 2, 'Color', 'Red'), 'Parent', app.video)
                %% Analyzing the BI/Red Box Wave
                BWWave = edge(createMask2(imgaussfilt(croppedVid, 3)), 'canny'); % ORIGINAL GAUSS IS 3
                %BWWave = flipud(BWWave);
                row = fillmissing(nan(1,size(BWWave,2)), 'previous');
                for k = 1:width(row)
                    try 
                        % Since createMask() surrounds the whole extracted wave, this gets only the top wave 
                        temp = find(BWWave(:,k),1,'first');
                        if isempty(temp)
                        else
                            row(k) = temp;
                        end
                    catch exception
                        throw(exception) 
                    end    
                end

                redBox = imcrop(BWWave, [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]);
                
                % Creates a NaN matrix the size of the red box
                BIrow = fillmissing(nan(1,size(redBox,2)), 'previous');
                % Loops through the each point along the wave to find
                % minima/maxima

                for k = 1:width(BIrow)
                    try 
                        % Since createMask() surrounds the whole extracted wave, this gets only the top wave 
                        temp = find(redBox(:,k),1,'first');
                        if isempty(temp)
                        else
                            BIrow(k) = temp;
                        end
                    catch exception
                        throw(exception) 
                    end    
                end
                
                % Extremely scientific method of finding the calm level
                if frameNum == 1
                   BIcalmLvl = mean(rmmissing(BIrow))
                   
                   BISinglePointCalmLvl = row(round(app.BIsinglePoint.Value))
                   AISinglePointCalmLvl = row(round(app.AIsinglePoint.Value))
                end
                BIamplitude = abs(min(BIrow) - max(BIrow))/2;
           
                % Calculate period
                if frameNum > 3
                    Period = calculatePeriodFrames(app, BISinglePointMatrix, timeMatrix);

                    last_two = rmmissing(timeMatrix);
                    timeCoefficent = last_two(end) - last_two(end-1);

                    BIPeriodMatrixFrames(frameNum, 1) = Period * timeCoefficent;
                %catch exception
                %    throw(exception) 
                end
                
                %% BIWavelengthMatrix(frameNum, 1) = calculateWavelength(app, BIrow, CMPerPixel);
                %calculateVelocity(app, BISinglePointMatrix, AISinglePointMatrix)


                % Implement a setting for the config file for the '100'
                %BIsinglePoint = BIrow(round(100)+1) - BIcalmLvl;
                %BIsinglePoint = row(round(app.BIsinglePoint.Value)) - BISinglePointCalmLvl;
                BIsinglePoint = BISinglePointCalmLvl - row(round(app.BIsinglePoint.Value)) ;

                BISinglePointMatrix(frameNum, 1) = BIsinglePoint * CMPerPixel;
                BIAmplitudeMatrix(frameNum, 1) = BIamplitude * CMPerPixel;
                timeMatrix(frameNum, 1) = toc(GraphTic);


                % Calculate velocity
                % try instead of doing it only at that one point to go
                % through the whole length of the wave
                % May want to create a matrix of each indiviual frame 
%% Velocity Algorithm 1
                
%                 if frameNum > 3
%                     if BISinglePointMatrix(frameNum - 1) > BISinglePointMatrix(frameNum) && BISinglePointMatrix(frameNum - 2) < BISinglePointMatrix(frameNum-1)
%                         for i = 1:20
%                             disp("Peak at single Point")
%                         end
%                         %VelocityInFrames = BISinglePointMatrix(frameNum - 1)
%                         bool = 0;
%                         slider = round(app.BIsinglePoint.Value);
%                         while bool == 0
%                             if row(slider) > row(slider+1)
%                                 distance = round(app.BIsinglePoint.Value) - slider;
%                                 distance = distance*CMPerPixel
%                                 bool = 1;
%                                 % lambda = v * T
%                                 wavelength = distance * Period
% 
%                             else
%                                 slider = slider + 1;
%                             end
%                             
%                         end
%                     end
%                 end

%% Velocity Algorithm 2
%                 if frameNum > 3
%                     if mod(frameNum, 2) == 0
%                         [pks, loc] = findpeaks(BIrow)
%                         
%                     else
%                         [pks2, loc2] = findpeaks(BIrow)
%                         if length(loc) > 1 && length(loc2) > 1
%                             loc2 = loc2(loc2 >= loc(1));
%                             wavelength = loc2(1) - loc(1)
%                             BIVelocityMatrix(frameNum, 1) = wavelength
%                         end
%                     end
%                 end

%% Velocity Algorithm 2.5
                if frameNum > 3
                    if mod(frameNum, 2) == 0
                        [pks, loc] = findpeaks(imgaussfilt(BIrow, 15));
                        
                    else
                        [pks2, loc2] = findpeaks(imgaussfilt(BIrow, 15));
                        if length(loc) > 0 && length(loc2) > 0
                            %loc2 = loc2(loc2 >= loc(1));
                            %velocity = loc2(1) - loc(1)
                            %BIVelocityMatrix(frameNum, 1) = wavelength
                            
                            velocity = loc2(1) - loc(1)
                            if velocity > 0 
                                BIVelocityMatrix(frameNum, 1) = velocity * CMPerPixel
                                BIWavelengthMatrix(frameNum, 1) = velocity * CMPerPixel * Period * timeCoefficent
                            else
                                BIVelocityMatrix(frameNum, 1) = 0;
                                BIWavelengthMatrix(frameNum, 1) = 0;
                            end
                        end
                    end
                end
      

%% Velocity Algorithm 3
%                 try 
%                     [pks, loc] = findpeaks(BIrow)
%                     wavelength = mean(diff(loc))
%                 catch
%                     disp("Hi")
%                 end

                % Plotting
                if strcmp(app.TimeElapsedUnitSwitch.Value, 'Frames') == 1
                    plot(app.amplitudeGraph1, BIAmplitudeMatrix, '-r')
                    plot(app.amplitudeGraph2, BIAmplitudeMatrix, '-r')
                    plot(app.singlePointGraph1, BISinglePointMatrix, '-r')
                    plot(app.singlePointGraph2, BISinglePointMatrix, '-r')
                    plot(app.periodGraph1, BIPeriodMatrixFrames, '-r')
                    plot(app.periodGraph2, BIPeriodMatrixFrames, '-r')
                    plot(app.velocityGraph1, rmmissing(BIVelocityMatrix), '-r')
                    %plot(app.velocityGraph1, BIVelocityMatrix, '-r')
                    
                elseif strcmp(app.TimeElapsedUnitSwitch.Value, 'Seconds') == 1
                    plot(app.amplitudeGraph1, timeMatrix, BIAmplitudeMatrix, '-r')
                    plot(app.amplitudeGraph2, timeMatrix, BIAmplitudeMatrix, '-r')
                    plot(app.singlePointGraph1, timeMatrix, BISinglePointMatrix, '-r')
                    plot(app.singlePointGraph2, timeMatrix, BISinglePointMatrix, '-r')
                    plot(app.periodGraph1, timeMatrix, BIPeriodMatrixFrames, '-r')
                    plot(app.periodGraph2, timeMatrix, BIPeriodMatrixFrames, '-r')
                    missingVelocityTime = find(ismissing(BIVelocityMatrix));
                    matrix1_cleaned = rmmissing(BIVelocityMatrix);
                    matrix2_cleaned = timeMatrix;
                    matrix2_cleaned(missingVelocityTime) = [];
                    plot(app.velocityGraph1, matrix2_cleaned, matrix1_cleaned, '-r')
                    plot(app.wavelengthGraph1, matrix2_cleaned, rmmissing(BIWavelengthMatrix), '-r')
                    
                end
                % May be able to simplify with 'hold off'
                hold(app.amplitudeGraph1, 'on')
                hold(app.amplitudeGraph2, 'on')
                hold(app.singlePointGraph1, 'on')
                hold(app.singlePointGraph2, 'on')
                hold(app.periodGraph1, 'on')
                hold(app.periodGraph2, 'on')
                

                %% Analyzing the AI/Blue Box Wave
                blueBox = imcrop(BWWave, [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]);
                
                % Creates a NaN matrix the size of the red box
                AIrow = fillmissing(nan(1,size(blueBox,2)), 'previous');

                % Loops through the each point along the wave to find
                % minima/maxima
                for k = 1:width(AIrow)
                    try 
                        temp = find(blueBox(:,k),1,'first');
                        if isempty(temp)
                        else
                            AIrow(k) = temp;
                        end
                    catch exception
                        throw(exception) 
                    end    
                end

                % Extremely scientific method of finding the calm level
                if frameNum == 1
                   AIcalmLvl = mean(rmmissing(AIrow));
                end
                AIamplitude = abs(max(AIrow) - min(AIrow))/2;

                % Calculate period
                if frameNum > 3
                    Period = calculatePeriodFrames(app, AISinglePointMatrix, timeMatrix);

                    last_two = rmmissing(timeMatrix);;
                    timeCoefficent = last_two(end) - last_two(end-1);

                    AIPeriodMatrixFrames(frameNum, 1) = Period * timeCoefficent;
                %catch exception
                %    throw(exception) 
                %end
                end
            

                % Implement a setting for the config file for the '100'
                %AIsinglePoint = AIrow(round(100)+1) - AIcalmLvl;
                AIsinglePoint = AISinglePointCalmLvl - row(round(app.AIsinglePoint.Value));
                %AIsinglePoint = row(round(app.AIsinglePoint.Value)) - AISinglePointCalmLvl;

                AISinglePointMatrix(frameNum, 1) = AIsinglePoint * CMPerPixel;
                AIAmplitudeMatrix(frameNum, 1) = AIamplitude * CMPerPixel;
                
                % Plotting''
                if strcmp(app.TimeElapsedUnitSwitch.Value, 'Frames') == 1
                    plot(app.amplitudeGraph1, AIAmplitudeMatrix, '-b')
                    plot(app.amplitudeGraph2, AIAmplitudeMatrix, '-b')
                    plot(app.singlePointGraph1, AISinglePointMatrix, '-b')
                    plot(app.singlePointGraph2, AISinglePointMatrix, '-b')
                    plot(app.periodGraph1, AIPeriodMatrixFrames, '-b')
                    plot(app.periodGraph2, AIPeriodMatrixFrames, '-b')
                elseif strcmp(app.TimeElapsedUnitSwitch.Value, 'Seconds') == 1
                    plot(app.amplitudeGraph1, timeMatrix, AIAmplitudeMatrix, '-b')
                    plot(app.amplitudeGraph2, timeMatrix, AIAmplitudeMatrix, '-b')
                    plot(app.singlePointGraph1, timeMatrix, AISinglePointMatrix, '-b')
                    plot(app.singlePointGraph2, timeMatrix, AISinglePointMatrix, '-b')
                    plot(app.periodGraph1, timeMatrix, AIPeriodMatrixFrames, '-b')
                    plot(app.periodGraph2, timeMatrix, AIPeriodMatrixFrames, '-b')
                end
                % May be able to simplify with 'hold off'
                hold(app.amplitudeGraph1, 'off')
                hold(app.amplitudeGraph2, 'off')
                hold(app.singlePointGraph1, 'off')
                hold(app.singlePointGraph2, 'off')
                hold(app.periodGraph1, 'off')
                hold(app.periodGraph2, 'off')

                % Shows the cropped videos of the red and blue boxes
                %RGBBWWave = double(255 * repmat(uint8(BWWave), 1, 1, 3));
                %imshow(imcrop((double(croppedVid) + RGBBWWave), [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.BIvideo)
                %imshow(imcrop((double(croppedVid) + RGBBWWave), [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.AIvideo)
                
                % shows the cropped videos of the red and blue boxes
                % (without white border)
                imshow(imcrop(croppedVid, [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.BIvideo)
                imshow(imcrop(croppedVid, [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.AIvideo)

                frameNum = frameNum + 1
                % Loops the video if it ends
                if strcmp(app.inputOption.Value,'Video') == 1
                    if isDone(originalVideo)
                        originalVideo = vision.VideoFileReader("OurWave2.mp4")
                    end
                end
            end
        end
        
        function resetSettingsView(app)
            if strcmp(app.inputOption.Value,'Video') == 1
                originalVideo = vision.VideoFileReader("OurWave2.mp4");
                videoPlayer = vision.VideoPlayer;
                videoPlayer(originalVideo) %moves it to the next frame I think
                imgPreview = imcrop(originalVideo(), [app.Crop1Slider.Value, app.Crop2Slider.Value, app.Crop3Slider.Value, app.Crop4Slider.Value]);
            else
                disp(app.inputOption.Value)
                %imgPreview = imcrop(removeFisheye(app, snapshot(webcam(app.inputOption.Value)), cameraParams), [app.Crop1Slider.Value, app.Crop2Slider.Value, app.Crop3Slider.Value, app.Crop4Slider.Value]);

                imgPreview = imcrop(snapshot(webcam(app.inputOption.Value)), [app.Crop1Slider.Value, app.Crop2Slider.Value, app.Crop3Slider.Value, app.Crop4Slider.Value]);
            end

            singlePointVid = insertShape(insertShape(imgPreview,'line', [app.AIsinglePoint.Value 0 app.AIsinglePoint.Value app.Crop4Slider.Value], 'LineWidth', 2, 'Color', 'blue'),'line', [app.BIsinglePoint.Value 0 app.BIsinglePoint.Value app.Crop4Slider.Value], 'LineWidth', 2, 'Color', 'red');
            vidWithRulerLines = insertShape(insertShape(singlePointVid,'line', [0 app.Line2Slider.Value app.Crop3Slider.Value app.Line2Slider.Value], 'LineWidth', 2, 'Color', 'blue'),'line', [0 app.Line1Slider.Value app.Crop3Slider.Value app.Line1Slider.Value], 'LineWidth', 2, 'Color', 'blue');

            imshow(insertShape(insertShape(vidWithRulerLines, 'filled-rectangle', [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Blue'), 'filled-rectangle', [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Red'), 'Parent', app.SettingsVideo)
            % Calculates the scale of pixels to cm
            pixelsPerCM = abs(app.Line1Slider.Value - app.Line2Slider.Value) / app.centimetersSpinner.Value
            app.pixelsPerCMValue.Text = string(pixelsPerCM);
            CMPerPixel = app.centimetersSpinner.Value / abs(app.Line1Slider.Value - app.Line2Slider.Value)
            app.CMPerPixelValue.Text = string(CMPerPixel);
        end
        
        
        function period = calculatePeriodFrames(app, SinglePointMatrix, timeMatrix)
            %[pks, loc] = findpeaks(imgaussfilt(BISinglePointMatrix, 5), timeMatrix);
            [pks, loc] = findpeaks(imgaussfilt(SinglePointMatrix, 5));
            pks = flip(pks);
            loc = flip(loc);
            if length(loc) < 2
                period = 0;
                return
            end
            period = loc(1) - loc(2);
        end
        
        function wavelength = calculateWavelength(app, BIrow, CMPerPixel)
            [pks, loc] = findpeaks(imgaussfilt(BIrow, 5));
            [ymin, locs_min] = findpeaks(-imgaussfilt(BIrow, 5));
            
            throughs = -ymin;

            try
                %wavelength = (throughs(1) - pks(1))
                wavelength = (loc(1) - locs_min(1)) * 2 * CMPerPixel
                return
            catch
                wavelength = 1
            end
        end
        
        function results = calculateVelocity(app, BISinglePointMatrix, AISinglePointMatrix)
            [pks, loc] = findpeaks(BISinglePointMatrix)
            
            %Find the most recent peak after this time in the AI point
            [pks2, loc2] = findpeaks(AISinglePointMatrix)
            if length(loc) > 3
                CroppedMatrix = AISinglePointMatrix(loc(end-1):end)
                VelocityWavelength = loc2(end) - loc(end-1)
                VelocityWavelength = VelocityWavelength / (abs(app.BIsinglePoint.Value - app.AIsinglePoint.Value))
                % pixels / frame
            else
                VelocityWavelength = 0
            end
            

            %see what time the peak at the first single point is
            %See what time the next peak at the second single point is
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % For switching between cameras
            dropDownCell = webcamlist';
            dropDownCell{end+1} = 'Video';
            app.inputOption.Items = dropDownCell;

            % Refreshes the viewer from the blank axes
            resetSettingsView(app)
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            global limit
            global increment
            limit = 1000;
            increment = 1000;
            
            global frameNum
            global BIamplitudeMatrix
            global AIamplitudeMatrix
            global BISinglePointMatrix
            global AISinglePointMatrix

            frameNum = 1;
            BIamplitudeMatrix = NaN(limit,1);
            BISinglePointMatrix = NaN(limit,1);
            AIamplitudeMatrix = NaN(limit,1);
            AISinglePointMatrix = NaN(limit,1);

            %% IF YOU WANT TO GET THIS WORKING AGAIN, ADD MORE MATRICIES!
            disp("a")
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            main(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            configFile = fopen('config.txt', 'wt');
            fprintf(configFile, '%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f', app.ViewBoxHeight.Value, app.ViewBoxWidth.Value, app.BIVerticalPosition.Value, app.BIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.AIHorizontalPosition.Value, app.BIsinglePoint.Value, app.AIsinglePoint.Value, app.Crop1Slider.Value, app.Crop4Slider.Value, app.Crop3Slider.Value, app.Crop4Slider.Value, app.Line1Slider.Value, app.Line2Slider.Value);
            fclose(configFile);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global crop
            configFile = fopen('config.txt', 'r');
            configSettings = fscanf(configFile, '%f');
            fclose(configFile);
            app.ViewBoxHeight.Value = configSettings(1);
            app.ViewBoxWidth.Value = configSettings(2);
            app.BIVerticalPosition.Value = configSettings(3);
            app.BIHorizontalPosition.Value = configSettings(4);
            app.AIVerticalPosition.Value = configSettings(5);
            app.AIHorizontalPosition.Value = configSettings(6);
            app.BIsinglePoint.Value = configSettings(7);
            app.AIsinglePoint.Value = configSettings(8);
            crop(1) = configSettings(9);
            crop(2) = configSettings(10);
            crop(3) = configSettings(11);
            crop(4) = configSettings(12);
            app.Line1Slider.Value = configSettings(13);
            app.Line2Slider.Value = configSettings(14);
            resetSettingsView(app)
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            resetSettingsView(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp("Attempting to stop")
            global on 
            on = 0;
        end

        % Button pushed function: ExportGraphsButton
        function ExportGraphsButtonPushed(app, event)
            app.ExportSettingsPanel.Visible = "on";
        end

        % Button pushed function: ExportGraphsButton2
        function ExportGraphsButton2Pushed(app, event)
            [status, msg] = mkdir("Exports", app.FolderNameEditField.Value);
            if status == 0
                warndlg(msg, "Something went wrong")
                return
            end
            if strcmp(msg, 'Directory already exists.') == 1
                warndlg(msg, "Try a different name")
                return
            else
                cd("Exports/" + app.FolderNameEditField.Value + "/")
                disp("Hi you did it")
                exportgraphics(app.amplitudeGraph1, 'AmplitudeGraph.jpg')
                exportgraphics(app.singlePointGraph1, 'SinglePointGraph.jpg')
                exportgraphics(app.periodGraph1, 'PeriodGraph.jpg')
                cd ..
                cd ..
                disp(ls())
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ExportSettingsPanel.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Settings = uitab(app.TabGroup);
            app.Settings.Title = 'Settings';
            app.SettingsVideo = uiaxes(app.Settings);
            title(app.SettingsVideo, 'Preview')
            zlabel(app.SettingsVideo, 'Z')
            app.LoadButton = uibutton(app.Settings, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.Settings, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ViewBoxSizeLabel = uilabel(app.Settings);
            app.ViewBoxSizeLabel.Text = 'View Box Size';
            app.SinglePointLabel = uilabel(app.Settings);
            app.SinglePointLabel.Text = 'Single Point';
            app.HeightLabel = uilabel(app.Settings);
            app.HeightLabel.Text = 'Height';
            app.ViewBoxHeight = uislider(app.Settings);
            app.ViewBoxHeight.Limits = [0 1080];
            app.ViewBoxHeight.Value = 90;
            app.WidthLabel = uilabel(app.Settings);
            app.WidthLabel.Text = 'Width';
            app.ViewBoxWidth = uislider(app.Settings);
            app.ViewBoxWidth.Limits = [0 1920];
            app.ViewBoxWidth.Value = 280;
            app.BeforeLabel = uilabel(app.Settings);
            app.BeforeLabel.Text = 'Before';
            app.BIsinglePoint = uislider(app.Settings);
            app.BIsinglePoint.Limits = [0 1920];
            app.BIsinglePoint.Value = 100;
            app.AfterLabel = uilabel(app.Settings);
            app.AfterLabel.Text = 'After';
            app.AIsinglePoint = uislider(app.Settings);
            app.AIsinglePoint.Limits = [0 1920];
            app.AIsinglePoint.Value = 100;
            app.BeforeImpactBoxPositionLabel = uilabel(app.Settings);
            app.BeforeImpactBoxPositionLabel.Text = 'Before Impact Box Position';
            app.AfterImpactBoxPositionLabel = uilabel(app.Settings);
            app.AfterImpactBoxPositionLabel.Text = 'After Impact Box Position';
            app.HorizontalSliderLabel = uilabel(app.Settings);
            app.HorizontalSliderLabel.Text = {'Horizontal'; ''};
            app.BIHorizontalPosition = uislider(app.Settings);
            app.BIHorizontalPosition.Limits = [0 1920];
            app.BIHorizontalPosition.Value = 700;
            app.HorizontalLabel = uilabel(app.Settings);
            app.HorizontalLabel.Text = 'Vertical';
            app.BIVerticalPosition = uislider(app.Settings);
            app.BIVerticalPosition.Limits = [0 1080];
            app.BIVerticalPosition.Value = 80;
            app.HorizontalSlider_2Label = uilabel(app.Settings);
            app.HorizontalSlider_2Label.Text = {'Horizontal'; ''};
            app.AIHorizontalPosition = uislider(app.Settings);
            app.AIHorizontalPosition.Limits = [0 1920];
            app.AIHorizontalPosition.Value = 200;
            app.VerticalSlider_2Label = uilabel(app.Settings);
            app.VerticalSlider_2Label.Text = 'Vertical';
            app.AIVerticalPosition = uislider(app.Settings);
            app.AIVerticalPosition.Limits = [0 1080];
            app.AIVerticalPosition.Value = 80;
            app.WidthLabel_2 = uilabel(app.Settings);
            app.WidthLabel_2.Text = 'Width';
            app.Crop1Slider = uislider(app.Settings);
            app.Crop1Slider.Limits = [0 1920];
            app.Crop1Slider.Value = 200;
            app.HeightLabel_2 = uilabel(app.Settings);
            app.HeightLabel_2.Text = 'Height';
            app.Crop2Slider = uislider(app.Settings);
            app.Crop2Slider.Limits = [0 1080];
            app.XpositionLabel = uilabel(app.Settings);
            app.XpositionLabel.Text = 'X position';
            app.Crop3Slider = uislider(app.Settings);
            app.Crop3Slider.Limits = [0 1920];
            app.Crop3Slider.Value = 1920;
            app.YPositionLabel = uilabel(app.Settings);
            app.YPositionLabel.Text = 'Y Position';
            app.Crop4Slider = uislider(app.Settings);
            app.Crop4Slider.Limits = [0 1080];
            app.Crop4Slider.Value = 1080;
            app.ViewButton = uibutton(app.Settings, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.WholeVideoSizeLabel = uilabel(app.Settings);
            app.WholeVideoSizeLabel.Text = 'Whole Video Size';
            app.CalibrationLabel = uilabel(app.Settings);
            app.CalibrationLabel.Text = 'Calibration';
            app.Line2Slider = uislider(app.Settings);
            app.Line2Slider.Limits = [0 1080];
            app.Line2Slider.Value = 110;
            app.BeforeLabel_2 = uilabel(app.Settings);
            app.BeforeLabel_2.Text = 'Line 1';
            app.Line1Slider = uislider(app.Settings);
            app.Line1Slider.Limits = [0 1080];
            app.Line1Slider.Value = 100;
            app.AfterLabel_2 = uilabel(app.Settings);
            app.AfterLabel_2.Text = 'Line 2';
            app.centimetersSpinnerLabel = uilabel(app.Settings);
            app.centimetersSpinnerLabel.Text = 'centimeters';
            app.centimetersSpinner = uispinner(app.Settings);
            app.centimetersSpinner.Limits = [0 Inf];
            app.centimetersSpinner.ValueDisplayFormat = '%d';
            app.centimetersSpinner.Value = 47;
            app.inputOption = uidropdown(app.Settings);
            app.VideoSourceLabel = uilabel(app.Settings);
            app.VideoSourceLabel.Text = 'Video Source';
            app.pixelsPerCMLabel = uilabel(app.Settings);
            app.pixelsPerCMLabel.Text = 'Pixels per centimeter:';
            app.pixelsPerCMValue = uilabel(app.Settings);
            app.pixelsPerCMValue.Text = '0';
            app.CMPerPixelLabel = uilabel(app.Settings);
            app.CMPerPixelLabel.Text = 'Centimeters per pixel';
            app.CMPerPixelValue = uilabel(app.Settings);
            app.CMPerPixelValue.Text = '0';
            app.Theheightofthetankis47centimetersLabel = uilabel(app.Settings);
            app.Theheightofthetankis47centimetersLabel.Text = 'The height of the tank is 47 centimeters';
            app.Viewer = uitab(app.TabGroup);
            app.Viewer.Title = 'Analyzer';
            app.video = uiaxes(app.Viewer);
            title(app.video, 'Viewer')
            app.BIvideo = uiaxes(app.Viewer);
            title(app.BIvideo, 'Red Box Viewer')
            app.AIvideo = uiaxes(app.Viewer);
            title(app.AIvideo, 'Blue Box Viewer')
            app.graphViewTab1 = uitabgroup(app.Viewer);
            app.amplitudeGraphTab1 = uitab(app.graphViewTab1);
            app.amplitudeGraphTab1.Title = 'Box Amplitude';
            app.amplitudeGraph1 = uiaxes(app.amplitudeGraphTab1);
            title(app.amplitudeGraph1, 'Amplitude Graph')
            xlabel(app.amplitudeGraph1, 'Time Elapsed')
            ylabel(app.amplitudeGraph1, 'Height (centimeters)')
            app.singlePointViewTab = uitab(app.graphViewTab1);
            app.singlePointViewTab.Title = 'Single Point';
            app.singlePointGraph1 = uiaxes(app.singlePointViewTab);
            title(app.singlePointGraph1, 'Single Point Amplitude Graph')
            xlabel(app.singlePointGraph1, 'Time Elapsed')
            ylabel(app.singlePointGraph1, 'Height (centimeters)')
            zlabel(app.singlePointGraph1, 'Z')
            app.PeriodTab1 = uitab(app.graphViewTab1);
            app.PeriodTab1.Title = 'Period';
            app.periodGraph1 = uiaxes(app.PeriodTab1);
            title(app.periodGraph1, 'Period')
            xlabel(app.periodGraph1, 'Time Elapsed')
            ylabel(app.periodGraph1, 'Period (seconds per cycle)')
            zlabel(app.periodGraph1, 'Z')
            app.wavelengthTab1 = uitab(app.graphViewTab1);
            app.wavelengthTab1.Title = 'Wavelength';
            app.wavelengthGraph1 = uiaxes(app.wavelengthTab1);
            title(app.wavelengthGraph1, 'Wavelength Graph')
            xlabel(app.wavelengthGraph1, 'Time Elapsed')
            ylabel(app.wavelengthGraph1, 'Wavelength (centimeters)')
            zlabel(app.wavelengthGraph1, 'Z')
            app.VelocityTab = uitab(app.graphViewTab1);
            app.VelocityTab.Title = 'Velocity';
            app.velocityGraph1 = uiaxes(app.VelocityTab);
            title(app.velocityGraph1, 'Velocity Graph')
            xlabel(app.velocityGraph1, 'Time Elapsed')
            ylabel(app.velocityGraph1, 'Velocity (CM per second)')
            zlabel(app.velocityGraph1, 'Z')
            app.graphViewTab2 = uitabgroup(app.Viewer);
            app.amplitudeGraphTab2 = uitab(app.graphViewTab2);
            app.amplitudeGraphTab2.Title = 'Box Amplitude';
            app.amplitudeGraph2 = uiaxes(app.amplitudeGraphTab2);
            title(app.amplitudeGraph2, 'Average Box Amplitude Graph')
            xlabel(app.amplitudeGraph2, 'Time Elapsed')
            ylabel(app.amplitudeGraph2, 'Height (centimeters)')
            app.singlePointViewTab2 = uitab(app.graphViewTab2);
            app.singlePointViewTab2.Title = 'Single Point';
            app.singlePointGraph2 = uiaxes(app.singlePointViewTab2);
            title(app.singlePointGraph2, 'Single Point Amplitude Graph')
            xlabel(app.singlePointGraph2, 'Time Elapsed')
            ylabel(app.singlePointGraph2, 'Height (centimeters)')
            zlabel(app.singlePointGraph2, 'Z')
            app.PeriodTab2 = uitab(app.graphViewTab2);
            app.PeriodTab2.Title = 'Period';
            app.periodGraph2 = uiaxes(app.PeriodTab2);
            title(app.periodGraph2, 'Period')
            xlabel(app.periodGraph2, 'Time Elapsed')
            ylabel(app.periodGraph2, 'Period (seconds per cycle)')
            zlabel(app.periodGraph2, 'Z')
            app.ResetDataButton = uibutton(app.Viewer, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.StartButton = uibutton(app.Viewer, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Viewer, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExportGraphsButton = uibutton(app.Viewer, 'push');
            app.ExportGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGraphsButtonPushed, true);
            app.ExportGraphsButton.Text = 'Export Graphs';
            app.ExportSettingsPanel = uipanel(app.Viewer);
            app.ExportSettingsPanel.Title = 'Export Settings';
            app.ExportSettingsPanel.Visible = 'off';
            app.FolderNameEditFieldLabel = uilabel(app.ExportSettingsPanel);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.ExportSettingsPanel, 'text');
            app.ExportGraphsButton2 = uibutton(app.ExportSettingsPanel, 'push');
            app.ExportGraphsButton2.ButtonPushedFcn = createCallbackFcn(app, @ExportGraphsButton2Pushed, true);
            app.ExportGraphsButton2.Text = 'Export Graphs';
            app.CloseButton = uibutton(app.ExportSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TimeElapsedUnitSwitch = uiswitch(app.Viewer, 'slider');
            app.TimeElapsedUnitSwitch.Items = {'Frames', 'Seconds'};
            app.TimeElapsedUnitSwitch.Value = 'Seconds';
            app.TimeElapsedUnitSwitchLabel = uilabel(app.Viewer);
            app.TimeElapsedUnitSwitchLabel.Text = 'Time Elapsed Unit';
            app.InstructionManualTab = uitab(app.TabGroup);
            app.InstructionManualTab.Title = 'Instruction Manual';
            app.InstructionmanualLabel = uilabel(app.InstructionManualTab);
            app.InstructionmanualLabel.Text = 'Instruction manual:';
            app.ViewBoxSizeLabel_2 = uilabel(app.InstructionManualTab);
            app.ViewBoxSizeLabel_2.Text = {'Settings:'; 'View Box Size: These sliders change the scale of BOTH red and blue boxes. Height controls vertical scale, whereas width controls horizontal scale.'; ''; 'Before and After Impact Box Position: changes the X and Y coordinates for each box corresponding to the color of the text. '; 'The words ''Before'' and ''After'' are arbitrary though, it was just as a method of standardization, however, it works even without being placed before or after.'; ''; 'Single Point View: These sliders control the graph on tab 2. On the viewer after pressing ''view'', if both points are in frame, you should see two vertical blue lines '; 'which represent the two points the program is finding the height of the wave for. '; ''; 'Whole Size Video: controls the size of the initial crop of the video (from what the camera sees to the video preview you see).'; ''; 'Calibration: these sliders control two blue horizontal lines which are used to see how many pixels are in a set amount of centimeters. '; 'To use this tool you should line up one of the lines with the top of the tank (where the tape is) and another one at the bottom (where it touches the table). '; 'You can also do this manually by lining up a ruler with the side of the tank and manually inputting the centimeter lines and the amount of centimeters.'; ''; 'Video Source: Changes what camera the program is using. The video option at the bottom is if you want to load a pre-recorded video (for development).  '; ''; 'Analyzer:'; 'Box Amplitude: This graph measures the average amplitude in the red or blue boxes. This graph is NOT reccomended because it does not work well if the '; 'box is not the size of at least one wavelength. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveTankAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end